classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField_9       matlab.ui.control.EditField
        EditField_9Label  matlab.ui.control.Label
        EditField_8       matlab.ui.control.EditField
        EditField_8Label  matlab.ui.control.Label
        EditField_7       matlab.ui.control.EditField
        EditField_7Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.EditField
        EditField_6Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        Label_9           matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        EditField_4Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        score_1;
        score_2;
        score_3; 
        sum_min=250;
       % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.score_1= app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.score_2 = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.score_3 = app.EditField_3.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            sum_min =250;
            sum=app.score_1+app.score_2+app.score_3;
            app.Label_2.Text=sprintf('%d',sum);
            if(app.sum_min<=sum)
                app.Label_4.Text = 'Поздравляем!';
            else
                app.Label_4.Text = ':(';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Проходной балл: 250';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Сумма баллов:';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Фамилия, Имя, Отчество';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Предмет';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Баллы ЕГЭ';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end