classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        ButtonGroup  matlab.ui.container.ButtonGroup
        CheckBox_2   matlab.ui.control.CheckBox
        CheckBox     matlab.ui.control.CheckBox
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
          
            if ((app.CheckBox.Value==1)&&(app.CheckBox_2.Value==1))
                x=0:0.01:4*pi;
                ys=sin(x);yc=cos(x);
                plot(app.UIAxes,x,ys,x,yc);
            end
            if ((app.CheckBox.Value==0)&&(app.CheckBox_2.Value==0))
                plot(app.UIAxes,0,0);
            end
            if ((app.CheckBox.Value==0)&&(app.CheckBox_2.Value==1))
                x=-2*pi:0.01:2*pi;
                yc=cos(x);
                plot(app.UIAxes,x,yc);
            end 
             if ((app.CheckBox.Value==1)&&(app.CheckBox_2.Value==0))
                x=-2*pi:0.01:2*pi;
                ys=sin(x);
                plot(app.UIAxes,x,ys);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Группа переключатилей';
            app.CheckBox = uicheckbox(app.ButtonGroup);
            app.CheckBox.Text = ' График синуса';
            app.CheckBox_2 = uicheckbox(app.ButtonGroup);
            app.CheckBox_2.Text = ' График косинуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end