classdef app22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_3  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
          [x, y] = meshgrid(linspace(-10, 10));
          fs = str2func(['@(x, y)' app.EditField.Value]);
          zs = fs(x, y);
          fp = str2func(['@(x, y)' app.EditField_2.Value]);
          zp = fp(x,y);
          surface(app.UIAxes, x, y, zs, 'FaceColor', [0.5 1.0 0.5], 'EdgeColor', 'none');
          surface(app.UIAxes, x, y, zp, 'FaceColor', [1.0 0.5 0.0], 'EdgeColor', 'none');
          hold(app.UIAxes,'on');
          zdiff = zs - zp;
          C = contours(x, y, zdiff, [0 0]);
          xL = C(1, 2:end);
          yL = C(2, 2:end);
          z = yL.^2+2*xL;
          plot3(app.UIAxes, xL, yL, z,'Color','b','MarkerSize',10);
          hold(app.UIAxes,'off');
          zL = interp2(x, y, zp, xL, yL);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'График ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' поверхность'; ' '};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'y.^2+2*x';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ' плоскость ';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = '2*y-x';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Изобразить ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end